classdef Calculate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Comsol              matlab.ui.Figure
        Button_pause        matlab.ui.control.Button
        Button_run          matlab.ui.control.Button
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        DropDown            matlab.ui.control.DropDown
        Label               matlab.ui.control.Label
        Label_import        matlab.ui.control.Label
        Label_min           matlab.ui.control.Label
        Label_step          matlab.ui.control.Label
        Label_max           matlab.ui.control.Label
        Label_jxh_in        matlab.ui.control.Label
        EditField_jxh_min   matlab.ui.control.NumericEditField
        EditField_jxh_step  matlab.ui.control.NumericEditField
        EditField_jxh_max   matlab.ui.control.NumericEditField
        Label_jxw_in        matlab.ui.control.Label
        EditField_jxw_min   matlab.ui.control.NumericEditField
        EditField_jxw_step  matlab.ui.control.NumericEditField
        EditField_jxw_max   matlab.ui.control.NumericEditField
        Label_cw_in         matlab.ui.control.Label
        EditField_cw_min    matlab.ui.control.NumericEditField
        EditField_cw_step   matlab.ui.control.NumericEditField
        EditField_cw_max    matlab.ui.control.NumericEditField
        Label_cln_crn_in    matlab.ui.control.Label
        EditField_crn_in    matlab.ui.control.NumericEditField
        EditField_cln_in    matlab.ui.control.NumericEditField
        gap                 matlab.ui.control.NumericEditField
        Label_gap           matlab.ui.control.Label
        Button_file_path    matlab.ui.control.Button
        OpenButton          matlab.ui.control.Button
        Knob_clear          matlab.ui.control.DiscreteKnob
        ClearOrNotLabel     matlab.ui.control.Label
        Tab_2               matlab.ui.container.Tab
        Label_import_2      matlab.ui.control.Label
        Gauge               matlab.ui.control.LinearGauge
        EditField_crn_out   matlab.ui.control.NumericEditField
        Label_crn_out       matlab.ui.control.Label
        EditField_cln_out   matlab.ui.control.NumericEditField
        Label_cln_out       matlab.ui.control.Label
        EditField_cw_out    matlab.ui.control.NumericEditField
        Label_cw_out        matlab.ui.control.Label
        EditField_jxw_out   matlab.ui.control.NumericEditField
        Label_jxw_out       matlab.ui.control.Label
        EditField_jxh_out   matlab.ui.control.NumericEditField
        Label_jxh_out       matlab.ui.control.Label
        delta_B             matlab.ui.control.NumericEditField
        Label_delta_B       matlab.ui.control.Label
        Label_back          matlab.ui.control.Label
        Label_image         matlab.ui.control.Label
        Image_line          matlab.ui.control.Image
        Tab_3               matlab.ui.container.Tab
        UITable             matlab.ui.control.Table
        Button_quit         matlab.ui.control.Button
        Image_logo          matlab.ui.control.Image
        Label_lab           matlab.ui.control.Label
    end


    properties (Access = public)
        file_path = 'D:\GitHub\paper1' % 默认文件夹地址
        clearValue = 0 %默认Unselected
        result_path = 'D:\GitHub\paper1\result_default' % 默认result文件夹地址
        pauseValue = 0

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_file_path
        function Button_file_pathPushed(app, event)
            app.file_path = uigetdir('*.*','请选择文件夹'); %fliepath为文件夹路径
        end

        % Button pushed function: Button_run
        function Button_runPushed(app, event)
            %pause('off')
            while 1
                clc;
                tic;
                app.Button_run.Icon = 'pause.jpg';
                app.Button_run.Text = 'runing';
                app.Button_run.FontColor = [1 0 0];
                if app.clearValue==1
                    app.result_path=uigetdir('*.*','请选择result文件夹');
                elseif app.clearValue==2
                    [status, message, messageid]=rmdir('result_default', 's');
                    %创建文件夹
                    mkdir result_default\data
                    mkdir result_default\picture
                    mkdir result_default\line
                    app.result_path = [app.file_path,'\result_default\'];
                elseif app.clearValue==3

                    resultname=char(inputdlg('请输入新文件名'));
                    mkdir(resultname);
                    mkdir(resultname, 'data');
                    mkdir(resultname, 'picture');
                    mkdir(resultname, 'line');
                    app.result_path = [app.file_path,'\',resultname];
                else
                    msgbox('Clear Or Not','操作错误','error');
                    app.Button_run.Icon = 'run.jpg';
                    app.Button_run.Text = 'run';
                    app.Button_run.FontColor = [0 0 0];
                    break;
                end
                data_path = [app.result_path,'\data\'];
                xian_path = [app.result_path,'\Line\'];
                tu_path = [app.result_path,'\picture\'];
                jxh0 = app.EditField_jxh_min.Value;
                jxw0 = app.EditField_jxw_min.Value;
                cw0 = app.EditField_cw_min.Value;
                jxh1 = app.EditField_jxh_max.Value;
                jxw1 = app.EditField_jxw_max.Value;
                cw1 = app.EditField_cw_max.Value;
                s1 = app.EditField_jxh_step.Value;
                s2 = app.EditField_jxw_step.Value;
                s3 = app.EditField_cw_step.Value;
                jx = app.gap.Value;
                clnin = app.EditField_cln_in.Value;
                crnin = app.EditField_crn_in.Value;
                jx = [ num2str(jx),'[mm]'];
                count = 0;
                All = ((jxh1-jxh0)/s1+1)*((jxw1-jxw0)/s2+1)*((cw1-cw0)/s3+1);
                app.EditField_crn_in.Value = All;
                for jxhn = jxh0 : s1 : jxh1
                    for jxwn = jxw0 : s2 : jxw1
                        for cwn = cw0:s3:cw1
                            wcn = cwn+jxwn;
                            cln = floor(21/wcn);
                            crn = cln;
                            cnz = cln + crn;
                            jxh= [ num2str(jxhn),'[mm]'];
                            jxw= [ num2str(jxwn),'[mm]'];
                            cw = [ num2str(cwn),'[mm]'];
                            wc = [ num2str(wcn),'[mm]'];
                            toc;
                            %Model(jxh, jxw, cw, jx, wc, cnz, cln, crn, model_path, data_path, xian_path,tu_path);
                            %site=['A',num2str(count+2)];
                            %analyse(jxh, jxw, cw, clnn, data_path, xlsx_path, site);
                            pause(1);
                            app.EditField_jxh_out.Value = jxhn;
                            app.EditField_jxw_out.Value = jxwn;
                            app.EditField_cw_out.Value = cwn;
                            app.EditField_cln_out.Value = cln;
                            app.EditField_crn_out.Value = crn;
                            count = count + 1;
                            app.EditField_cln_in.Value = count;
                            app.Gauge.Value = floor(count*100/All);
                            if app.pauseValue==1 %暂停程序
                                f=msgbox('已暂停，关闭窗口继续','提醒','help');
                                uiwait(f)
                                disp('Program execution has resumed');
                                app.pauseValue=0;
                                app.Button_pause.Icon = 'pause.jpg';
                                app.Button_pause.Text = 'pause';
                                app.Button_pause.FontColor = [0 0 0];
                            end
                        end
                    end
                end
                app.Button_run.Icon = 'run.jpg';
                app.Button_run.Text = 'run';
                app.Button_run.FontColor = [0 0 0];
                break;
            end

        end

        % Button pushed function: Button_quit
        function Button_quitPushed(app, event)
            delete(app.Comsol)
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            open('D:\All Softwares\COMSOL56\Multiphysics\bin\win64\comsolmphserver.exe')
        end

        % Value changed function: Knob_clear
        function Knob_clearValueChanged(app, event)
            app.clearValue = str2num(app.Knob_clear.Value);
        end

        % Button pushed function: Button_pause
        function Button_pausePushed(app, event)
            if app.pauseValue == 0
                app.pauseValue = 1;
                app.Button_pause.Icon = 'run.jpg';
                app.Button_pause.Text = 'go on';
                app.Button_pause.FontColor = [1 0 0];
            end
        end

        % Window key press function: Comsol
        function ComsolWindowKeyPress(app, event)
            %监听键盘
            key = event.Key;
            %考虑使用switch语句进行判断
            switch key
                case "return"
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Comsol = uifigure('Visible', 'off');
            app.Comsol.Name = '基于COMSOL与MATLAB的磁性液体密封磁场参数化仿真软件';
            app.Comsol.Icon = 'comsol.jpg';
            app.Comsol.WindowKeyPressFcn = createCallbackFcn(app, @ComsolWindowKeyPress, true);
            app.Label_lab = uilabel(app.Comsol);
            app.Label_lab.Text = '磁性液体研究所';
            app.Image_logo = uiimage(app.Comsol);
            app.Image_logo.ImageSource = '北京交通大学logo.png';
            app.Button_quit = uibutton(app.Comsol, 'push');
            app.Button_quit.ButtonPushedFcn = createCallbackFcn(app, @Button_quitPushed, true);
            app.Button_quit.Icon = 'switch.png';
            app.Button_quit.Text = 'quit';
            app.TabGroup = uitabgroup(app.Comsol);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '输入';
            app.ClearOrNotLabel = uilabel(app.Tab);
            app.ClearOrNotLabel.Text = 'Clear Or Not';
            app.Knob_clear = uiknob(app.Tab, 'discrete');
            app.Knob_clear.Items = {'Unselected', 'Choice', 'Clear', 'New'};
            app.Knob_clear.ItemsData = {'0', '1', '2', '3', ''};
            app.Knob_clear.ValueChangedFcn = createCallbackFcn(app, @Knob_clearValueChanged, true);
            app.Knob_clear.Value = '0';
            app.OpenButton = uibutton(app.Tab, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Icon = 'comsol.jpg';
            app.OpenButton.Text = 'Open';
            app.Button_file_path = uibutton(app.Tab, 'push');
            app.Button_file_path.ButtonPushedFcn = createCallbackFcn(app, @Button_file_pathPushed, true);
            app.Button_file_path.Icon = 'file.jpg';
            app.Button_file_path.Text = '选择文件地址';
            app.Label_gap = uilabel(app.Tab);
            app.Label_gap.Text = '间隙';
            app.gap = uieditfield(app.Tab, 'numeric');
            app.gap.Value = 0.1;
            app.EditField_cln_in = uieditfield(app.Tab, 'numeric');
            app.EditField_crn_in = uieditfield(app.Tab, 'numeric');
            app.Label_cln_crn_in = uilabel(app.Tab);
            app.Label_cln_crn_in.Text = '左右齿宽';
            app.EditField_cw_max = uieditfield(app.Tab, 'numeric');
            app.EditField_cw_max.Value = 0.28;
            app.EditField_cw_step = uieditfield(app.Tab, 'numeric');
            app.EditField_cw_step.Value = 0.02;
            app.EditField_cw_min = uieditfield(app.Tab, 'numeric');
            app.EditField_cw_min.Value = 0.12;
            app.Label_cw_in = uilabel(app.Tab);
            app.Label_cw_in.Text = '齿宽';
            app.EditField_jxw_max = uieditfield(app.Tab, 'numeric');
            app.EditField_jxw_max.Value = 1.4;
            app.EditField_jxw_step = uieditfield(app.Tab, 'numeric');
            app.EditField_jxw_step.Value = 0.1;
            app.EditField_jxw_min = uieditfield(app.Tab, 'numeric');
            app.EditField_jxw_min.Value = 0.4;
            app.Label_jxw_in = uilabel(app.Tab);
            app.Label_jxw_in.Text = '槽宽';
            app.EditField_jxh_max = uieditfield(app.Tab, 'numeric');
            app.EditField_jxh_max.Value = 1;
            app.EditField_jxh_step = uieditfield(app.Tab, 'numeric');
            app.EditField_jxh_step.Value = 0.1;
            app.EditField_jxh_min = uieditfield(app.Tab, 'numeric');
            app.EditField_jxh_min.Value = 0.3;
            app.Label_jxh_in = uilabel(app.Tab);
            app.Label_jxh_in.Text = '槽高';
            app.Label_max = uilabel(app.Tab);
            app.Label_max.Text = '最大值';
            app.Label_step = uilabel(app.Tab);
            app.Label_step.Text = '步进';
            app.Label_min = uilabel(app.Tab);
            app.Label_min.Text = '最小值';
            app.Label_import = uilabel(app.Tab);
            app.Label_import.Text = '输入';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '结果文件夹处理方式';
            app.DropDown = uidropdown(app.Tab);
            app.DropDown.Items = {'未选择', 'Clear', 'Choice', 'New'};
            app.DropDown.Value = '未选择';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '输出';
            app.Image_line = uiimage(app.Tab_2);
            app.Image_line.ImageSource = '测试用图片.png';
            app.Label_image = uilabel(app.Tab_2);
            app.Label_image.Text = '磁场密度模曲线图';
            app.Label_back = uilabel(app.Tab_2);
            app.Label_back.Text = '当前所完成计算的参数';
            app.Label_delta_B = uilabel(app.Tab_2);
            app.Label_delta_B.Text = '总磁通密度模';
            app.delta_B = uieditfield(app.Tab_2, 'numeric');
            app.Label_jxh_out = uilabel(app.Tab_2);
            app.Label_jxh_out.Text = '槽高';
            app.EditField_jxh_out = uieditfield(app.Tab_2, 'numeric');
            app.Label_jxw_out = uilabel(app.Tab_2);
            app.Label_jxw_out.Text = '槽宽';
            app.EditField_jxw_out = uieditfield(app.Tab_2, 'numeric');
            app.Label_cw_out = uilabel(app.Tab_2);
            app.Label_cw_out.Text = '齿宽';
            app.EditField_cw_out = uieditfield(app.Tab_2, 'numeric');
            app.Label_cln_out = uilabel(app.Tab_2);
            app.Label_cln_out.Text = '左齿数';
            app.EditField_cln_out = uieditfield(app.Tab_2, 'numeric');
            app.Label_crn_out = uilabel(app.Tab_2);
            app.Label_crn_out.Text = '右齿数';
            app.EditField_crn_out = uieditfield(app.Tab_2, 'numeric');
            app.Gauge = uigauge(app.Tab_2, 'linear');
            app.Label_import_2 = uilabel(app.Tab_2);
            app.Label_import_2.Text = '进度';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '表格';
            app.UITable = uitable(app.Tab_3);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Button_run = uibutton(app.Comsol, 'push');
            app.Button_run.ButtonPushedFcn = createCallbackFcn(app, @Button_runPushed, true);
            app.Button_run.Icon = 'run.jpg';
            app.Button_run.Text = 'run';
            app.Button_pause = uibutton(app.Comsol, 'push');
            app.Button_pause.ButtonPushedFcn = createCallbackFcn(app, @Button_pausePushed, true);
            app.Button_pause.Icon = 'pause.jpg';
            app.Button_pause.Text = 'pause';
            % Show the figure after all components are created
            app.Comsol.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Comsol)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Comsol)
        end
    end
end